classdef SorptionFluidNELF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InputsTab                     matlab.ui.container.Tab
        InletgasconcLabel_2           matlab.ui.control.Label
        InletgasconcLabel             matlab.ui.control.Label
        OutletgasconcLabel            matlab.ui.control.Label
        InletliquidrateLabel          matlab.ui.control.Label
        InletliquidconcLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ymxLabel                      matlab.ui.control.Label
        DataentrymethodSwitch         matlab.ui.control.Switch
        DataentrymethodLabel          matlab.ui.control.Label
        mEditField                    matlab.ui.control.NumericEditField
        mEditFieldLabel               matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        RunstatusEditField            matlab.ui.control.EditField
        RunstatusEditFieldLabel       matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        G1EditField                   matlab.ui.control.NumericEditField
        G1EditFieldLabel              matlab.ui.control.Label
        Y1EditField                   matlab.ui.control.NumericEditField
        Y1EditField_2Label            matlab.ui.control.Label
        Y2EditField                   matlab.ui.control.NumericEditField
        Y2EditFieldLabel              matlab.ui.control.Label
        L2EditField                   matlab.ui.control.NumericEditField
        L2EditFieldLabel              matlab.ui.control.Label
        X2EditField                   matlab.ui.control.NumericEditField
        X2EditFieldLabel              matlab.ui.control.Label
        ResultsTab                    matlab.ui.container.Tab
        NumberofstagesEditField       matlab.ui.control.NumericEditField
        NumberofstagesEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Data  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x_c1=[0;0.05;0.1;0.15;0.2;0.25;0.3;0.35;0.4;0.45;0.5;0.55;0.6;0.65;0.7;0.75;0.8;0.85;0.9;0.95;1];
            y_c1=[0;0.004784689;0.009174312;0.013215859;0.016949153;0.020408163;0.023622047;0.02661597;0.029411765;0.03202847;0.034482759;0.036789298;0.038961039;0.041009464;0.042944785;0.044776119;0.046511628;0.04815864;0.049723757;0.051212938;0.052631579];
          
            app.Data = table(x_c1,y_c1);
            app.UITable.Data =table2cell(app.Data);
            app.UITable.ColumnEditable=[true true];
            app.mEditField.Value = 0.01;
            app.X2EditField.Value = 0.2;
            app.Y2EditField.Value = 0.004;
            app.Y1EditField.Value = 0.01;
            app.L2EditField.Value = 1;
            app.G1EditField.Value = 100;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%Clearing the plot.
cla(app.UIAxes)

            
%Take the values of all inputs in variables first
Y1 = app.Y1EditField.Value;
Y2 = app.Y2EditField.Value;
G1 = app.G1EditField.Value;
X2 = app.X2EditField.Value;
L2 = app.L2EditField.Value;


Ls = L2/(1+X2);
Gs = G1/(1+Y1);
slopeopl = Ls/Gs;
X1 = X2 + (Y1-Y2)/slopeopl;

%Storing Non-Equilibrium Lattice Fluid data in X and Y arrays
switch app.DataentrymethodSwitch.Value
    case 'm'
        m = app.mEditField.Value;
        X = linspace(X2,X1);
        Y = m.*X./(1+X.*(1-m));
      
        
    case 'Enter values manually'
       app.Data=cell2table(app.UITable.Data);
       X=table2array(app.Data(:,1));
       Y=table2array(app.Data(:,2));
end

%y=fcn(x)
h = griddedInterpolant(X, Y, 'pchip');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


%Checking is Y2 is above the equillibrium curve
stopsignal = 0;
Y2_equilibrium = h(X2);
if Y2 < Y2_equilibrium
    errormsg = 'error: Exit gas concentration lesser than Non-Equilibrium Lattice Fluid Concentration.';
    app.RunstatusEditField.Value = errormsg;
    stopsignal = 1;
end

%Finding number of stages%%%%%%%%%%%%%%%%%%%%%%%%%%%%

n=0;
maxstagesallowed = 100;
stagepointsx = [];
stagepointsy = [Y1];

if stopsignal ==0
    
    intersects = @(x)(slopeopl*(x-X2)+Y2 - h(x));
    intrx = fzero(intersects, (X1+X2)/2);
    
    if intrx>X2 && intrx<X1
        
        errormg = 'Liquid rate less than minimum. Please increase L2 and run again.'
        app.RunstatusEditField.Value = errormg;
    else
        b = X1;
        stagepointsx = [stagepointsx, b];
        while n < maxstagesallowed
            yy = h(b);
            stagepointsy = [stagepointsy, yy];
            
            xx = X2 + (yy-Y2)/slopeopl;
            stagepointsx = [stagepointsx, xx];
            
            if xx>X2
                n = n+1;
                b = xx;
            else
                yy = h(xx);
                stagepointsy = [stagepointsy, yy];
                break
            end
        end
        app.RunstatusEditField.Value = 'Run successful. Check Results tab.';
    end    
end        
n = n+1;

%Plotting Non-Equilibrium Lattice Fluid and Operating line
opline = @(x)(slopeopl*(x-X2)+Y2);
Ye = opline(X);
hold (app.UIAxes,'on')
plot(app.UIAxes, X, Ye);
plot(app.UIAxes, X, Y);

lx = length(stagepointsx)-1;

%Plotting stages
for j=1:lx
    p1 = [stagepointsx(j) stagepointsx(j)];
    p2 = [stagepointsy(j) stagepointsy(j+1)];
    plot(app.UIAxes, p1, p2);
    
    p3 = [stagepointsx(j) stagepointsx(j+1)];
    p4 = [stagepointsy(j+1) stagepointsy(j+1)];
    plot(app.UIAxes, p3, p4);
    
end
app.NumberofstagesEditField.Value = n;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.X2EditFieldLabel = uilabel(app.InputsTab);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.InputsTab, 'numeric');
            app.L2EditFieldLabel = uilabel(app.InputsTab);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.InputsTab, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.InputsTab);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.InputsTab, 'numeric');
            app.Y1EditField_2Label = uilabel(app.InputsTab);
            app.Y1EditField_2Label.Text = 'Y1';
            app.Y1EditField = uieditfield(app.InputsTab, 'numeric');
            app.G1EditFieldLabel = uilabel(app.InputsTab);
            app.G1EditFieldLabel.Text = 'G1';
            app.G1EditField = uieditfield(app.InputsTab, 'numeric');
            app.RunButton = uibutton(app.InputsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RunstatusEditFieldLabel = uilabel(app.InputsTab);
            app.RunstatusEditFieldLabel.Text = 'Run status';
            app.RunstatusEditField = uieditfield(app.InputsTab, 'text');
            app.UITable = uitable(app.InputsTab);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.mEditFieldLabel = uilabel(app.InputsTab);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.InputsTab, 'numeric');
            app.DataentrymethodLabel = uilabel(app.InputsTab);
            app.DataentrymethodLabel.Text = 'Data entry method:';
            app.DataentrymethodSwitch = uiswitch(app.InputsTab, 'slider');
            app.DataentrymethodSwitch.Items = {'m', 'Enter values manually'};
            app.DataentrymethodSwitch.Value = 'm';
            app.ymxLabel = uilabel(app.InputsTab);
            app.ymxLabel.Text = '(y = m*x)';
            app.Image = uiimage(app.InputsTab);
            app.Image.ImageSource = 'Gas_Absorber_Diagram.png';
            app.InletliquidconcLabel = uilabel(app.InputsTab);
            app.InletliquidconcLabel.Text = 'Inlet liquid conc';
            app.InletliquidrateLabel = uilabel(app.InputsTab);
            app.InletliquidrateLabel.Text = 'Inlet liquid rate';
            app.OutletgasconcLabel = uilabel(app.InputsTab);
            app.OutletgasconcLabel.Text = 'Outlet gas conc';
            app.InletgasconcLabel = uilabel(app.InputsTab);
            app.InletgasconcLabel.Text = 'Inlet gas conc';
            app.InletgasconcLabel_2 = uilabel(app.InputsTab);
            app.InletgasconcLabel_2.Text = 'Inlet gas conc';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NumberofstagesEditFieldLabel = uilabel(app.ResultsTab);
            app.NumberofstagesEditFieldLabel.Text = 'Number of stages';
            app.NumberofstagesEditField = uieditfield(app.ResultsTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SorptionFluidNELF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end